classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Image2                         matlab.ui.control.Image
        StressMPaEditField             matlab.ui.control.NumericEditField
        StressMPaEditFieldLabel        matlab.ui.control.Label
        VolumelitersEditField          matlab.ui.control.NumericEditField
        VolumelitersEditFieldLabel     matlab.ui.control.Label
        StressMPaGauge                 matlab.ui.control.Gauge
        StressMPaGaugeLabel            matlab.ui.control.Label
        VolumeGauge                    matlab.ui.control.LinearGauge
        VolumeGaugeLabel               matlab.ui.control.Label
        FBGStrainEditField             matlab.ui.control.NumericEditField
        FBGStrainEditFieldLabel        matlab.ui.control.Label
        TotalMasskgEditField           matlab.ui.control.NumericEditField
        TotalMasskgEditFieldLabel      matlab.ui.control.Label
        FactorofSafetyEditField        matlab.ui.control.NumericEditField
        FactorofSafetyEditFieldLabel   matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        TotaltanklitersEditField       matlab.ui.control.NumericEditField
        TotaltanklitersEditFieldLabel  matlab.ui.control.Label
        FBGResponseLabel               matlab.ui.control.Label
        StressResponseLabel            matlab.ui.control.Label
        UploadButtonFBG                matlab.ui.control.Button
        UploadButtonStress             matlab.ui.control.Button
        NewTankCheckBox                matlab.ui.control.CheckBox
        ChooseInputDropDown            matlab.ui.control.DropDown
        ChooseInputDropDownLabel       matlab.ui.control.Label
        SpecificGravityEditField       matlab.ui.control.NumericEditField
        SpecificGravityEditFieldLabel  matlab.ui.control.Label
        ValueEditField                 matlab.ui.control.NumericEditField
        ValueEditFieldLabel            matlab.ui.control.Label
        DefaultTankCheckBox            matlab.ui.control.CheckBox
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fbgFile             %file handle for the FBG response
        fbgPath             %Path to the file with FBG if needed
        stressFile          %file handle for the stress response
        stressPath          %path to the file for stress response
        peakWavelength      %peak wavelength found in the file
        safe                %boolean to determine whether the container is safe or not
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NewTankCheckBox
        function NewTankCheckBoxValueChanged(app, event)
            value = app.NewTankCheckBox.Value;
            app.DefaultTankCheckBox.Value = ~value;
            app.TotaltanklitersEditField.Enable = value;
            app.TotaltanklitersEditFieldLabel.Enable = value;
            app.StressResponseLabel.Enable = value;
            %app.FBGResponseLabel.Enable = value;
           % app.UploadButtonFBG.Enable = value;
            app.UploadButtonStress.Enable = value;

        end

        % Value changed function: DefaultTankCheckBox
        function DefaultTankCheckBoxValueChanged(app, event)
            value = ~app.DefaultTankCheckBox.Value;
            app.NewTankCheckBox.Value = value;
            app.TotaltanklitersEditField.Enable = value;
            app.TotaltanklitersEditFieldLabel.Enable = value;
            app.StressResponseLabel.Enable = value;
            %app.FBGResponseLabel.Enable = value;
            %app.UploadButtonFBG.Enable = value;
            app.UploadButtonStress.Enable = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.fbgFile ~= 0)
                openFile;
            else
                errordlg("FBG Response file not loaded!","File error"); 
                return;
            end
            %Calculate volume

            simulate;
        end

        % Button pushed function: UploadButtonStress
        function UploadButtonStressPushed(app, event)
            app.stressFile = uigetfile({'*.*'});
        end

        % Button pushed function: UploadButtonFBG
        function UploadButtonFBGPushed(app, event)
            [app.fbgFile, app.fbgPath] = uigetfile({'*.*'});
            %fbgFile = uigetfile(".asc")
        end

        % Value changed function: VolumelitersEditField
        function VolumelitersEditFieldValueChanged(app, event)

        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            msgbox(['Specific gravity is the ratio of the density of a substance compared to' ...
                ' some standard substance. For this application, it is water at a density of 1kg/l.' ...
                ''],'Help',"help");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FBG Sensor')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Arbitrary Unit')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'Gaelic logo cmyk 6_12_18-01.png');
            app.DefaultTankCheckBox = uicheckbox(app.UIFigure);
            app.DefaultTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultTankCheckBoxValueChanged, true);
            app.DefaultTankCheckBox.Text = 'Default Tank';
            app.DefaultTankCheckBox.Value = true;
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueEditField.Limits = [0 Inf];
            app.SpecificGravityEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityEditFieldLabel.Text = 'Specific Gravity';
            app.SpecificGravityEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityEditField.Limits = [0 Inf];
            app.SpecificGravityEditField.Value = 1;
            app.ChooseInputDropDownLabel = uilabel(app.UIFigure);
            app.ChooseInputDropDownLabel.Text = 'Choose Input';
            app.ChooseInputDropDown = uidropdown(app.UIFigure);
            app.ChooseInputDropDown.Items = {'Liquid Height (mm)', 'Volume (liters)'};
            app.ChooseInputDropDown.Value = 'Liquid Height (mm)';
            app.NewTankCheckBox = uicheckbox(app.UIFigure);
            app.NewTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @NewTankCheckBoxValueChanged, true);
            app.NewTankCheckBox.Text = 'New Tank';
            app.UploadButtonStress = uibutton(app.UIFigure, 'push');
            app.UploadButtonStress.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonStressPushed, true);
            app.UploadButtonStress.Icon = fullfile(pathToMLAPP, 'images', 'upload.jpg');
            app.UploadButtonStress.Enable = 'off';
            app.UploadButtonStress.Text = 'Upload';
            app.UploadButtonFBG = uibutton(app.UIFigure, 'push');
            app.UploadButtonFBG.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonFBGPushed, true);
            app.UploadButtonFBG.Icon = fullfile(pathToMLAPP, 'images', 'upload.jpg');
            app.UploadButtonFBG.Text = 'Upload';
            app.StressResponseLabel = uilabel(app.UIFigure);
            app.StressResponseLabel.Enable = 'off';
            app.StressResponseLabel.Text = 'Stress Response';
            app.FBGResponseLabel = uilabel(app.UIFigure);
            app.FBGResponseLabel.Text = 'FBG Response';
            app.TotaltanklitersEditFieldLabel = uilabel(app.UIFigure);
            app.TotaltanklitersEditFieldLabel.Enable = 'off';
            app.TotaltanklitersEditFieldLabel.Text = 'Total tank (liters)';
            app.TotaltanklitersEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotaltanklitersEditField.Enable = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Value = {'Unknown.'};
            app.Lamp = uilamp(app.UIFigure);
            app.FactorofSafetyEditFieldLabel = uilabel(app.UIFigure);
            app.FactorofSafetyEditFieldLabel.Text = 'Factor of Safety';
            app.FactorofSafetyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorofSafetyEditField.Editable = 'off';
            app.TotalMasskgEditFieldLabel = uilabel(app.UIFigure);
            app.TotalMasskgEditFieldLabel.Text = 'Total Mass (kg)';
            app.TotalMasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalMasskgEditField.Editable = 'off';
            app.FBGStrainEditFieldLabel = uilabel(app.UIFigure);
            app.FBGStrainEditFieldLabel.Text = 'FBG Strain';
            app.FBGStrainEditField = uieditfield(app.UIFigure, 'numeric');
            app.FBGStrainEditField.Editable = 'off';
            app.VolumeGaugeLabel = uilabel(app.UIFigure);
            app.VolumeGaugeLabel.Text = 'Volume(%)';
            app.VolumeGauge = uigauge(app.UIFigure, 'linear');
            app.StressMPaGaugeLabel = uilabel(app.UIFigure);
            app.StressMPaGaugeLabel.Text = 'Stress (MPa)';
            app.StressMPaGauge = uigauge(app.UIFigure, 'circular');
            app.StressMPaGauge.Limits = [0 275];
            app.VolumelitersEditFieldLabel = uilabel(app.UIFigure);
            app.VolumelitersEditFieldLabel.Text = 'Volume (liters)';
            app.VolumelitersEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumelitersEditField.ValueChangedFcn = createCallbackFcn(app, @VolumelitersEditFieldValueChanged, true);
            app.VolumelitersEditField.Editable = 'off';
            app.StressMPaEditFieldLabel = uilabel(app.UIFigure);
            app.StressMPaEditFieldLabel.Text = 'Stress(MPa)';
            app.StressMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.StressMPaEditField.Editable = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'infoIcon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end